classdef RGB_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RestoredefaultButton      matlab.ui.control.Button
        CancelButton_2            matlab.ui.control.Button
        CancelButton              matlab.ui.control.Button
        ApplychangeandexitButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        main_interface
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            %% make sure to add colour detecter object as an input arg
            app.main_interface = callingApp;
        end

        % Button pushed function: CancelButton_2
        function CancelButton_2Pushed(app, event)
            enableButtons(app.main_interface)
            delete(app)
        end

        % Button pushed function: ApplychangeandexitButton
        function ApplychangeandexitButtonPushed(app, event)
            %%update values
            enableButtons(app.main_interface)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            enableButtons(app.main_interface)
            delete(app)     
        end

        % Button pushed function: RestoredefaultButton
        function RestoredefaultButtonPushed(app, event)
            %% add callback

            enableButtons(app.main_interface)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ApplychangeandexitButton = uibutton(app.UIFigure, 'push');
            app.ApplychangeandexitButton.ButtonPushedFcn = createCallbackFcn(app, @ApplychangeandexitButtonPushed, true);
            app.ApplychangeandexitButton.Text = 'Apply change and exit';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.Text = {'Cancel'; ''};
            app.CancelButton_2 = uibutton(app.UIFigure, 'push');
            app.CancelButton_2.ButtonPushedFcn = createCallbackFcn(app, @CancelButton_2Pushed, true);
            app.CancelButton_2.Text = {'Cancel'; ''};
            app.RestoredefaultButton = uibutton(app.UIFigure, 'push');
            app.RestoredefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RestoredefaultButtonPushed, true);
            app.RestoredefaultButton.Text = 'Restore default';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RGB_interface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end